classdef CPPappTEST < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CPPAppUIFigure               matlab.ui.Figure
        InputLabel                   matlab.ui.control.Label
        SetParameterslLabel          matlab.ui.control.Label
        SensorthicknesstSliderLabel  matlab.ui.control.Label
        SthicknessSlider             matlab.ui.control.Slider
        SensordiameterdSliderLabel   matlab.ui.control.Label
        SdiameterSlider              matlab.ui.control.Slider
        ModelradiusRSliderLabel      matlab.ui.control.Label
        MradiusSlider                matlab.ui.control.Slider
        OutputLabel                  matlab.ui.control.Label
        SthicknessLabel              matlab.ui.control.Label
        SthicknessValLabel           matlab.ui.control.Label
        SdiameterLabel               matlab.ui.control.Label
        SdiameterValLabel            matlab.ui.control.Label
        MradiusLabel                 matlab.ui.control.Label
        MradiusValLabel              matlab.ui.control.Label
        CPPabsoluteLabel             matlab.ui.control.Label
        CPPpercentLabel              matlab.ui.control.Label
        CPPabsoluteValLabel          matlab.ui.control.Label
        CPPpercentValLabel           matlab.ui.control.Label
        CTPLabel                     matlab.ui.control.Label
        CTPValLabel                  matlab.ui.control.Label
        TabGroup                     matlab.ui.container.TabGroup
        CPPVisualizationTab          matlab.ui.container.Tab
        UIAxes                       matlab.ui.control.UIAxes
        UIAxes2                      matlab.ui.control.UIAxes
        PressureDistributionTab      matlab.ui.container.Tab
        UIAxes3                      matlab.ui.control.UIAxes
        SetPressurelLabel            matlab.ui.control.Label
        PressureSliderLabel          matlab.ui.control.Label
        PressureSlider               matlab.ui.control.Slider
    end


    properties (Access = private)
        
%         cpp = @(x,y,z,c) c*((1 + (sqrt(2*(y./z)+(y./z).^2)/tan(x./2)))/(1 + (y./z)));
        cpp = @(x,y,z,c) c*((sin(acos(z./(y+z))+0.5*x))./(sin(0.5*x)));
        ctp = @(x,y,z) ((z+y)/z)*(((x/z)+2*sqrt(1-(z/(z+y)).^2))/(2*acos(z/(z+y))+(x/z)));
        
        imageSize = 1000;
        
        youngE = 1.11e6;
                
    end
    
    methods (Access = private)
        
        function updatePlot(app)
            
            [thickness,diameter,radius,pressure] = getSliderValues(app);
            [CTP, CPP, CPP2, CPP3, CPPpc, xRange] = cppfunc(app,thickness,diameter,radius);
            scaleFactor = (app.imageSize/(radius+thickness+5))/2;
            [x0, y0, xSensor, ySensor, xDelta, yDelta, xBandage, yBandage] = polarfunc(app,thickness,diameter,radius,pressure,CPP);
            image = visfunc(app, diameter, thickness, radius, pressure, CPP,scaleFactor);
                                    
            app.SthicknessValLabel.Text = num2str(round(thickness,1));
            app.SdiameterValLabel.Text = num2str(round(diameter,1));
            app.MradiusValLabel.Text = num2str(round(radius,1));
            app.CTPValLabel.Text = num2str(CTP);
            app.CPPabsoluteValLabel.Text = num2str(round(CPP,3));
            app.CPPpercentValLabel.Text = num2str(round(CPPpc));
            
            cla(app.UIAxes)
            plot(app.UIAxes, xRange(1,:), CPP2, 'b');
            plot(app.UIAxes, log(thickness/radius), CPP, 'ro');
            title(app.UIAxes, strcat('CPP and trend line with constant \alpha = ', strcat(num2str((diameter*180)/(radius*pi)), '°')));
            
            cla(app.UIAxes2)
            plot(app.UIAxes2, xRange(2,:)*(180/pi), CPP3, 'b');
            plot(app.UIAxes2, (diameter/radius)*(180/pi), CPP, 'ro');
            title(app.UIAxes2, strcat('CPP and trend line with constant ratio t/R = ', num2str(thickness/radius)));
            
            cla(app.UIAxes3)
%             imshow(image,'Parent',app.UIAxes3);
%             app.UIAxes3.XLim = [-(max(ySensor)+5), (max(ySensor)+5)];
            xvalS = (scaleFactor.*xSensor + ones(1,numel(xSensor))*(size(image,1)/2));
            yvalS = (scaleFactor.*ySensor + ones(1,numel(ySensor))*(size(image,1)/2));
            xvalB = (scaleFactor.*xBandage + ones(1,numel(xBandage))*(size(image,1)/2));
            yvalB = (scaleFactor.*yBandage + ones(1,numel(yBandage))*(size(image,1)/2));
            imshow(image,'Parent', app.UIAxes3, 'Colormap', hot);
            plot(app.UIAxes3, xvalS, (ones(1,numel(yvalS))*size(image,2))-yvalS, 'LineWidth', 2);
            plot(app.UIAxes3, xvalB, (ones(1,numel(yvalB))*size(image,2))-yvalB, 'LineWidth', 2);
%             plot(app.UIAxes3, xval, yval, 'LineWidth', 1.5);
%             plot(app.UIAxes3, xSensor, ySensor, 'LineWidth', 1.5);
%             plot(app.UIAxes3, xDelta, yDelta);
%             plot(app.UIAxes3, xBandage, yBandage);
            
        end
        
        
        function [CTP, CPP, CPP2, CPP3, CPPpc, xRange] = cppfunc(app,thickness,diameter,radius)
            
            radiusRange = [1:1:1500];
            
            CTP = app.ctp(diameter,thickness,radius);
            CPP = app.cpp(diameter/radius,thickness,radius,CTP);
            CPPpc = CPP*100-100;
            
            xRange(1,:) = log(thickness./radiusRange);
            xRange(2,:) = diameter./radiusRange;
            %             CPP2 = app.cpp(diameter/radius,thickness,radiusRange(:));
            CPP2 = arrayfun(@(x) app.cpp(diameter/radius,thickness,x,CTP), radiusRange(1:end));
            CPP3 = arrayfun(@(x) app.cpp(diameter/x,thickness,radius,CTP), radiusRange(1:end));
            
        end
        
        function [x0, y0, xSensor, ySensor, xDelta, yDelta, xBandage, yBandage] = polarfunc(app,thickness,diameter,radius,pressure,CPP)
            
            theta0 = linspace(0,2*pi);
            thetaSensor = [linspace((pi/2)-0.5*(diameter/radius),(pi/2)+0.5*(diameter/radius)), fliplr(linspace((pi/2)-0.5*(diameter/radius),(pi/2)+0.5*(diameter/radius))), (pi/2)-0.5*(diameter/radius)];
            rho0 = ones(1,numel(theta0))*radius;
            rhoSensor = [ones(1,(numel(thetaSensor)-1)/2)*(radius+0.1), ones(1,(numel(thetaSensor-1)-1)/2)*(radius+thickness), radius+0.1];
            
            thetaDelta = [linspace(0,(pi/2)-0.5*(diameter/radius)-acos(radius/(radius+thickness))), linspace((pi/2)+0.5*(diameter/radius)+acos(radius/(radius+thickness)), (2*pi))];
            rhoDelta = ones(1,numel(thetaDelta))*(radius-10*((pressure*radius)/app.youngE));
            rhoDeltaS = ones(1,numel(thetaSensor))*(radius-10*((pressure*radius*CPP)/app.youngE));
            rhoBandage = ones(1,numel(thetaDelta))*(radius+0.2);
            rhoBandageS = ones(1,numel(thetaSensor))*(radius+thickness+0.2);
                        
            thetaDelta = cat(2,thetaDelta, thetaSensor);
            [thetaDelta, sorti] = sort(thetaDelta);
            rhoDelta = cat(2,rhoDelta, rhoDeltaS);
            rhoBandage = cat(2,rhoBandage,rhoBandageS);
            rhoDelta = rhoDelta(sorti);
            rhoBandage = rhoBandage(sorti);
            
            [x0, y0] = pol2cart(theta0, rho0);
            [xSensor, ySensor] = pol2cart(thetaSensor, rhoSensor);
            [xDelta, yDelta] = pol2cart(thetaDelta, rhoDelta);
            [xBandage, yBandage] = pol2cart(thetaDelta, rhoBandage);
                        
        end
        
        function image = visfunc(app,diameter,thickness,radius,pressure,CPP,scaleFactor)
            
            imageArray1 = ones(app.imageSize);
            imageArray = imageArray1;
                        
            imageArray(app.imageSize/2,app.imageSize/2) = 0;
                        
            polyvert = [size(imageArray,1)/2, size(imageArray,1)/2; tan(diameter/(2*radius))*(app.imageSize/2)+(app.imageSize/2), 0; -tan(diameter/(2*radius))*(app.imageSize/2)+(app.imageSize/2), 0];
            polyobj = roipoly(imageArray1, polyvert(:,1), polyvert(:,2));
            
            b = bwdist(imcomplement(imageArray));
                        
            imageArray(b < (scaleFactor*radius)) = 0;
            
            polyRest = immultiply(imageArray,polyobj);
            
            b1 = bwdist(imageArray);
            b2 = (ones(app.imageSize)*max(max(b1))-b1)./max(max(b1));
            c = bwdist(polyRest);
            c1 = (ones(app.imageSize)*max(max(c))-c)./max(max(c));
            d1 = imcomplement(imageArray).*c1;
            d2 = imcomplement(imageArray).*b2;
            ff = pressure/(160*133.32);
            d3 = (((d1*CPP)+d2)*ff)/2;
            d4 = d3 + imageArray;
            
            figure();
            imshow(d4, 'Colormap', parula);
%             figure();imshow(mat2gray(d2), 'Colormap', hot);
                                               
            circlepart = immultiply(polyobj,imcomplement(imageArray));
            
            imageArray(b > (scaleFactor*(radius-scaleFactor*((pressure*radius)/app.youngE))) & b < (scaleFactor*radius)) = (b(b > (scaleFactor*(radius-scaleFactor*((pressure*radius)/app.youngE))) & b < (scaleFactor*radius))-(scaleFactor*(radius-scaleFactor*((pressure*radius)/app.youngE))))/(scaleFactor*radius);
            
            imageArray(b > (scaleFactor*(radius-scaleFactor*((pressure*radius*CPP)/app.youngE))) & b < (scaleFactor*radius) & circlepart == 1) = (b(b > (scaleFactor*(radius-scaleFactor*((pressure*radius*CPP)/app.youngE))) & b < (scaleFactor*radius) & circlepart == 1)-(scaleFactor*(radius-scaleFactor*((pressure*radius*CPP)/app.youngE))))/(scaleFactor*radius);
            
            image = imageArray;
            
        end
        
        function [thickness,diameter,radius,pressure] = getSliderValues(app)
            
            thickness = app.SthicknessSlider.Value;
            diameter = app.SdiameterSlider.Value;
            radius = app.MradiusSlider.Value;
            pressure = app.PressureSlider.Value*133.32;
            
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            xlabel(app.UIAxes, 'log(t/R)');
            ylabel(app.UIAxes, 'CPP');
        
            xlabel(app.UIAxes2, 'Angle \alpha [°]');
            ylabel(app.UIAxes2, 'CPP');
            
            updatePlot(app);
            
        end

        % Value changed function: SthicknessSlider
        function SthicknessValueChanged(app, event)
            
            updatePlot(app);
            
        end

        % Value changed function: SdiameterSlider
        function SdiameterValueChanged(app, event)
            
            updatePlot(app);
            
        end

        % Value changed function: MradiusSlider
        function MradiusValueChanged(app, event)
            
            updatePlot(app);
            
        end

        % Value changed function: PressureSlider
        function PressureSliderValueChanged(app, event)
            
            updatePlot(app);
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CPPAppUIFigure = uifigure;
            app.CPPAppUIFigure.Name = 'CPP App';
            setAutoResize(app, app.CPPAppUIFigure, true)
            app.InputLabel = uilabel(app.CPPAppUIFigure);
            app.InputLabel.Text = '    Input';
            app.SetParameterslLabel = uilabel(app.CPPAppUIFigure);
            app.SetParameterslLabel.Text = 'Set your Parameters of Sensor and Model in mm:';
            app.SensorthicknesstSliderLabel = uilabel(app.CPPAppUIFigure);
            app.SensorthicknesstSliderLabel.Text = 'Sensorthickness t:';
            app.SthicknessSlider = uislider(app.CPPAppUIFigure);
            app.SthicknessSlider.Limits = [0.1 5];
            app.SthicknessSlider.ValueChangedFcn = createCallbackFcn(app, @SthicknessValueChanged, true);
            app.SthicknessSlider.Value = 0.1;
            app.SensordiameterdSliderLabel = uilabel(app.CPPAppUIFigure);
            app.SensordiameterdSliderLabel.Text = 'Sensordiameter d:';
            app.SdiameterSlider = uislider(app.CPPAppUIFigure);
            app.SdiameterSlider.Limits = [5 60];
            app.SdiameterSlider.ValueChangedFcn = createCallbackFcn(app, @SdiameterValueChanged, true);
            app.SdiameterSlider.Value = 5;
            app.ModelradiusRSliderLabel = uilabel(app.CPPAppUIFigure);
            app.ModelradiusRSliderLabel.Text = 'Modelradius R:';
            app.MradiusSlider = uislider(app.CPPAppUIFigure);
            app.MradiusSlider.Limits = [25 80];
            app.MradiusSlider.ValueChangedFcn = createCallbackFcn(app, @MradiusValueChanged, true);
            app.MradiusSlider.Value = 25;
            app.OutputLabel = uilabel(app.CPPAppUIFigure);
            app.OutputLabel.Text = '    Output';
            app.SthicknessLabel = uilabel(app.CPPAppUIFigure);
            app.SthicknessLabel.Text = ' Sensorthickness [mm]: ';
            app.SthicknessValLabel = uilabel(app.CPPAppUIFigure);
            app.SthicknessValLabel.Text = '';
            app.SdiameterLabel = uilabel(app.CPPAppUIFigure);
            app.SdiameterLabel.Text = ' Sensordiameter [mm]: ';
            app.SdiameterValLabel = uilabel(app.CPPAppUIFigure);
            app.SdiameterValLabel.Text = '';
            app.MradiusLabel = uilabel(app.CPPAppUIFigure);
            app.MradiusLabel.Text = ' Modelradius [mm]: ';
            app.MradiusValLabel = uilabel(app.CPPAppUIFigure);
            app.MradiusValLabel.Text = '';
            app.CPPabsoluteLabel = uilabel(app.CPPAppUIFigure);
            app.CPPabsoluteLabel.Text = ' CPP absolute: ';
            app.CPPpercentLabel = uilabel(app.CPPAppUIFigure);
            app.CPPpercentLabel.Text = ' Error perturbation [%]:';
            app.CPPabsoluteValLabel = uilabel(app.CPPAppUIFigure);
            app.CPPabsoluteValLabel.Text = '';
            app.CPPpercentValLabel = uilabel(app.CPPAppUIFigure);
            app.CPPpercentValLabel.Text = '';
            app.CTPLabel = uilabel(app.CPPAppUIFigure);
            app.CTPLabel.Text = ' CTP: ';
            app.CTPValLabel = uilabel(app.CPPAppUIFigure);
            app.CTPValLabel.Text = '';
            app.TabGroup = uitabgroup(app.CPPAppUIFigure);
            app.CPPVisualizationTab = uitab(app.TabGroup);
            app.CPPVisualizationTab.Title = 'CPP Visualization';
            app.CPPVisualizationTab.Units = 'pixels';
            app.UIAxes = uiaxes(app.CPPVisualizationTab);
            title(app.UIAxes, 'Title');
            xlabel(app.UIAxes, 'X');
            ylabel(app.UIAxes, 'Y');
            app.UIAxes.XLim = [-7 -2];
            app.UIAxes.XLimMode = 'manual';
            app.UIAxes.YLim = [0 8];
            app.UIAxes.YLimMode = 'manual';
            app.UIAxes2 = uiaxes(app.CPPVisualizationTab);
            title(app.UIAxes2, 'Title');
            xlabel(app.UIAxes2, 'X');
            ylabel(app.UIAxes2, 'Y');
            app.UIAxes2.XLim = [0 120];
            app.UIAxes2.XLimMode = 'manual';
            app.UIAxes2.YLim = [0 8];
            app.UIAxes2.YLimMode = 'manual';
            app.PressureDistributionTab = uitab(app.TabGroup);
            app.PressureDistributionTab.Title = 'Pressure Distribution';
            app.PressureDistributionTab.Units = 'pixels';
            app.UIAxes3 = uiaxes(app.PressureDistributionTab);
            title(app.UIAxes3, 'Pressure Influence by Sensor');
            app.SetPressurelLabel = uilabel(app.PressureDistributionTab);
            app.SetPressurelLabel.Text = 'Set your Pressure of Bandage Application in mmHg:';
            app.PressureSliderLabel = uilabel(app.PressureDistributionTab);
            app.PressureSliderLabel.Text = 'Pressure';
            app.PressureSlider = uislider(app.PressureDistributionTab);
            app.PressureSlider.ValueChangedFcn = createCallbackFcn(app, @PressureSliderValueChanged, true);
        end
    end

    methods (Access = public)

        % Construct app
        function app = CPPappTEST()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CPPAppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CPPAppUIFigure)
        end
    end
end